classdef project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label                    matlab.ui.control.Label
        AnimateButton            matlab.ui.control.Button
        RadiusEditField          matlab.ui.control.NumericEditField
        RadiusEditFieldLabel     matlab.ui.control.Label
        LatitudeEditField        matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel   matlab.ui.control.Label
        LongitudeEditField       matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Latitude = 0;
        Longitude = 180;
        Radius = 200;
        Sphere_Center = [1150, 0, 300];
        Sphere_Radius = 450;
        Steps = 100;
        Robot;
        Ik;
    end
    
    methods (Access = private)
        
        % Function to initialize the robot
        function initializeRobot(app)
            % Define DH parameters for each joint
            L1 = Link('d',      0, 'a',     0, 'alpha',  pi/2, 'qlim', deg2rad([-175, 175])); % Joint 1
            L2 = Link('d',    197, 'a',   647, 'alpha',     0, 'qlim', deg2rad([-175, 175])); % Joint 2
            L3 = Link('d', -123.5, 'a', 600.5, 'alpha',     0, 'qlim', deg2rad([-175, 175])); % Joint 3
            L4 = Link('d',  127.8, 'a',     0, 'alpha',  pi/2, 'qlim', deg2rad([-175, 175])); % Joint 4
            L5 = Link('d',  102.5, 'a',     0, 'alpha', -pi/2, 'qlim', deg2rad([-175, 175])); % Joint 5
            L6 = Link('d',     94, 'a',     0, 'alpha',     0, 'qlim', deg2rad([-175, 175])); % Joint 6
        
            % Combine links into a SerialLink robot
            app.Robot = SerialLink([L1 L2 L3 L4 L5 L6], 'name', '6DOF Robot');
        end

        % Function to find app.Steps equidistant points along a circle on the sphere
        function circle_points = findEquidistantPoints(app)
            % Convert app.Longitude and app.Latitude to radians
            longitude = deg2rad(app.Longitude);
            latitude = deg2rad(app.Latitude);
        
            % Normal vector to the sphere's surface at the given point
            normal_vector = [cos(latitude) * cos(longitude), ...
                             cos(latitude) * sin(longitude), ...
                             sin(latitude)];
        
            % Calculate the correct distance from the sphere center to the circle center
            distance_to_circle_center = sqrt(app.Sphere_Radius^2 - app.Radius^2);
            circle_center = app.Sphere_Center + distance_to_circle_center * normal_vector;
        
            % Generate equidistant points on the circle in the tangent plane
            angles = linspace(0, 2 * pi, app.Steps);
            circle_points = zeros(app.Steps, 3);
        
            % Tangent vectors defining the plane of the circle
            u = [-sin(longitude), cos(longitude), 0];  % East direction
            v = cross(normal_vector, u);  % Orthogonal to the surface
        
            % Normalize tangent vectors
            u = u / norm(u);
            v = v / norm(v);
        
            for i = 1:app.Steps
                circle_points(i, :) = circle_center + app.Radius * (cos(angles(i)) * u + sin(angles(i)) * v);
            end
        end

        function valid = calculations(app, circle_points)
            app.AnimateButton.Enable = "off";
            
            % Calculate if the robot can access the circle
            longitude = deg2rad(app.Longitude);
            latitude = deg2rad(app.Latitude);
            
            % Normal vector at the given Latitude and Longitude
            normal_vector = [cos(latitude) * cos(longitude), ...
                             cos(latitude) * sin(longitude), ...
                             sin(latitude)];
            
            % Distance from sphere center to circle center
            distance_to_circle_center = sqrt(app.Sphere_Radius^2 - app.Radius^2);
            if distance_to_circle_center < 0
                app.Label.Text = 'Invalid geometry: sphere radius smaller than circle radius.';
                return;  % Exit if geometry is invalid
            end
            
            % Calculate the robot's reach (ensure that the robot can access the circle)
            max_reach = sum([app.Robot.links.a]) + sum(abs([app.Robot.links.d]));
            if distance_to_circle_center > max_reach
                app.Label.Text = 'Robot cannot reach the circle.';
                return;  % Exit if the robot cannot reach the circle
            end
            
            
            % Initialize an array to store valid kinematics
            valid_kinematics = [];
            app.Ik = [];
            
            % Initial joint angles
            q_ik = zeros(1, app.Robot.n);
            valid = 1;
            
            % Loop through each point in the circle for kinematics calculation
            for i = 1:app.Steps
                
                % Get the current point for kinematics
                current_point = circle_points(i, :);
                
                % Calculate the normal vector to the sphere at the current point
                normal = app.Sphere_Center - current_point;
                normal = normal / norm(normal);  % Normalized vector
                
                % Calculate the target transformation matrix
                z_axis = normal;
                
                % Decide on the y-axis to avoid alignment issues with the z-axis
                if abs(dot(z_axis, [1, 0, 0])) > 0.999
                    y_axis = cross([0, 1, 0], z_axis);  % Use another reference axis
                else
                    y_axis = cross([1, 0, 0], z_axis);  % Default reference axis
                end
                
                % Normalize the y-axis and compute x-axis
                y_axis = y_axis / norm(y_axis);
                x_axis = cross(y_axis, z_axis);  % Orthogonal x-axis
                
                % Construct the rotation matrix from the axes
                R_target = [x_axis; y_axis; z_axis]';
                
                % Transformation matrix (position + rotation)
                T_target = transl(current_point(1), current_point(2), current_point(3)) * r2t(R_target);
                
                % Try the inverse kinematics calculation inside a try-catch block
                try
                    % Calculate IK solution
                    q_ik = app.Robot.ikine(T_target, 'mask', [1 1 1 1 1 1], 'q0', q_ik, 'tol', 1e-3, 'ilimit', 500);
                    
                    % Check if the solution is valid
                    if any(isnan(q_ik)) || any(isinf(q_ik))
                        error('Invalid IK solution (NaN or Inf values).');
                    end
                    
                    % Store valid solution
                    valid_kinematics = [valid_kinematics; q_ik];  % Append to the array
                    
                catch
                    % Display the error message if IK fails
                    app.Label.Text = "IK impossible, will not converge";
                    app.UIAxes.XLim = [-1500, 1750];
                    app.UIAxes.YLim = [-1500, 1500];
                    app.UIAxes.ZLim = [-750, 1250];
                    valid = 0;
                end
            end
            if valid
                app.Ik = valid_kinematics;
                app.AnimateButton.Enable = "on";
            end
        end

        % Function to plot the sphere and circle
        function plot(app, circle_points)

            app.AnimateButton.Enable = "off";

            cla;
            % Create and plot the sphere on the app's UIAxes
            app.UIAxes.XLim = [-1500, 1750];
            app.UIAxes.YLim = [-1500, 1500];
            app.UIAxes.ZLim = [-750, 1250];
            [x, y, z] = sphere(50);
            surf(app.UIAxes, ...
                x * app.Sphere_Radius + app.Sphere_Center(1), ...
                y * app.Sphere_Radius + app.Sphere_Center(2), ...
                z * app.Sphere_Radius + app.Sphere_Center(3), ...
                'FaceAlpha', 0.5, 'EdgeColor', 'none');
            
            hold on;
            
            % Plot the circle on the app's UIAxes
            plot3(app.UIAxes, circle_points(:, 1), circle_points(:, 2), circle_points(:, 3), 'r', 'LineWidth', 1.5);
            
            % Customize the plot
            title(app.UIAxes, 'End-Effector Tracing Circle on Sphere Surface');
            xlabel(app.UIAxes, 'X-axis')
            ylabel(app.UIAxes, 'Y-axis')
            zlabel(app.UIAxes, 'Z-axis')
            
            % Set axis limits and appearance
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            
            % Now animate the robot with the valid kinematics solutions
            hold on;
            for i = 1:size(app.Ik, 1)
                app.UIAxes.XLim = [-1500, 1750];
                app.UIAxes.YLim = [-1500, 1500];
                app.UIAxes.ZLim = [-750, 1250];

                app.Robot.plot(app.Ik(i, :));  % Plot each valid IK solution
                pause(0.01);  % Adjust the pause time for smoother or faster animation
            end
            
            app.AnimateButton.Enable = "on";
        end


        % Checks if the parameters entered are within real ranges
        function valid = validRange(app)
            valid = 1;
            % Initialize an empty error message
            errorMessage = "";
        
            % Check the Radius
            if app.Radius > 450 || app.Radius <= 0
                errorMessage = errorMessage + "Radius is invalid, must be in range of [0, 450]. ";
                valid = 0;
            end
        
            % Check the Latitude
            if app.Latitude > 90 || app.Latitude < -90
                errorMessage = errorMessage + "Latitude is invalid, must be in range of [-90, 90]. ";
                valid = 0;
            end
        
            % Check the Longitude
            if app.Longitude - 180 > 180 || app.Longitude - 180 < -180
                errorMessage = errorMessage + "Longitude is invalid, must be in range of [-180, 180]. ";
                valid = 0;
            end
        
            % Display error messages or enable the animate button
            if errorMessage == ""
                app.Label.Text = ""; % No errors
                app.AnimateButton.Enable = "on"; % Enable the button
            else
                app.Label.Text = errorMessage; % Show all error messages
                app.AnimateButton.Enable = "off"; % Disable the button
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeRobot(app);
            app.RadiusEditField.Value = 200;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            valid = validRange(app);
            if valid
                circle_points = findEquidistantPoints(app);
                valid = calculations(app, circle_points);
            end
            if valid
                disp("valid")
                plot(app, circle_points);
            end
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            app.AnimateButton.Enable = "off";
            app.Radius = app.RadiusEditField.Value;
            valid = validRange(app);
            if valid
                circle_points = findEquidistantPoints(app);
                calculations(app, circle_points);
            end

        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            app.AnimateButton.Enable = "off";
            app.Latitude = app.LatitudeEditField.Value;
            valid = validRange(app);
            if valid
                circle_points = findEquidistantPoints(app);
                calculations(app, circle_points);
            end
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            app.AnimateButton.Enable = "off";
            app.Longitude = app.LongitudeEditField.Value;
            app.Longitude = app.Longitude + 180;
            valid = validRange(app);
            if valid
                circle_points = findEquidistantPoints(app);
                calculations(app, circle_points);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'End-Effector Tracing Circle on Sphere Surface')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.LatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end